classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EStopButton  matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
    end

    
    properties (Access = public)
        eStopController  % Instance of EStopController
        UR3MovementInst  % Instance of UR3Movement
        ABBMovementInst  % Instance of ABBMovement
    end
    
    methods (Access = public)
        function initializeAppWithInstances(app, UR3MovementInstance, ABBMovementInstance, EStopControllerInstance)
             % Assign the instances to the app properties
            app.UR3MovementInst = UR3MovementInstance;
            app.ABBMovementInst = ABBMovementInstance;
            app.eStopController = EStopControllerInstance;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EStopButton
        function EStopButtonToggled(app, event)
            if ~app.eStopController.eStopEngaged
                % Engage the emergency stop on both robots
                app.eStopController.EngageEStop();
                app.EStopButton.Text = 'Resume';
            else
                % Disengage the emergency stop on both robots
                app.eStopController.DisengageEStop();
                app.EStopButton.Text = 'EStop';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonToggled, true);
            app.EStopButton.Text = 'EStop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end